classdef TwoChannelsKif7Gli2_SquareMask_13x13 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ReanalyzeButton          matlab.ui.control.Button
        MINSpinner               matlab.ui.control.Spinner
        MINSpinnerLabel          matlab.ui.control.Label
        MAXSpinner               matlab.ui.control.Spinner
        MAXSpinnerLabel          matlab.ui.control.Label
        Switch                   matlab.ui.control.Switch
        SwitchLabel              matlab.ui.control.Label
        filename                 matlab.ui.control.EditField
        FilenameEditFieldLabel   matlab.ui.control.Label
        AutoAnalyzeButton        matlab.ui.control.Button
        ImportCiliadataButton    matlab.ui.control.Button
        ImportKIF7dataButton     matlab.ui.control.Button
        loadButton               matlab.ui.control.Button
        SaveButton               matlab.ui.control.Button
        Framenumber              matlab.ui.control.Spinner
        FramenumberSpinnerLabel  matlab.ui.control.Label
        Kif7only                 matlab.ui.control.UIAxes
        Kif7IntensityCilia       matlab.ui.control.UIAxes
        Ciliaonly                matlab.ui.control.UIAxes
        Kif7TipIntensity         matlab.ui.control.UIAxes
    end

    
        properties (Access = private)
        namei
        pathi
        namej
        nframes
        Kif7Image
        Kif7_X %Tracks Kif7 center
        Kif7_Y %Tracks Kif7 center
        Background_X %Background center
        Background_Y %Background center
        I %Matrix to store Kif7 data
        J %Matrix to store Cilia data
        Results %Storing Data
        threshold %Threshold for Cilia Mask
        end

    
    methods (Access = private)
    %This function calculates the average signal and noise of the mask     
        function [signal, background] = calculate_mask(app,matrix)
            signal = sum(matrix,'all');
            background = 0;
        end
        
        function ImageButtonDownCallback(app, source, eventargs)
            switch app.Switch.Value
                case 'Signal'
                    orignal_size = size(app.I);
                    u=app.Kif7only.CurrentPoint;
                    column=int16(u(1,1));
                    row=int16(u(1,2));
                    
                    mask_total = 0;
                    mask_noise = 0;
                    mask_max = 0;
                    best_column = column;
                    best_row = row;

                    for i = -10:10
                        for j = -10:10
                            if row+i-6>=1 && row+i+6<=orignal_size(1) && column+j-6>=1 && column+j+6<=orignal_size(2) 
                                ROI = app.I(row+i-6:row+i+6,column+j-6:column+j+6);
                                [total, background] = calculate_mask(app,ROI);
                                if total > mask_total
                                    mask_total = total;
                                    best_column = column + j;
                                    best_row = row + i;
                                    %mask_noise = background; %Update Background
                                    %mask_max = max(ROI,[],'all');
                                end
                            end
                        end
                    end
                    app.Kif7_X=best_column;
                    app.Kif7_Y=best_row;
                    app.Results(app.Framenumber.Value,1)=best_column;
                    app.Results(app.Framenumber.Value,2)=best_row;
                    app.Results(app.Framenumber.Value,3)=mask_total;
                    mask_noise = sum(app.I(app.Background_Y-6:app.Background_Y+6,app.Background_X-6:app.Background_X+6),'all');
                    app.Results(app.Framenumber.Value,4)=mask_noise;
                    app.Results(app.Framenumber.Value,5)=(app.Results(app.Framenumber.Value,3)-app.Results(app.Framenumber.Value,4));
%             best_column
%             best_row
%             mask_total
%             mask_noise
%             mask_max
                    hold(app.Kif7only,'on');
                    hold(app.Ciliaonly,'on');
                    X=[best_column-6 best_column-6 best_column+6 best_column+6 best_column-6];
                    Y=[best_row-6 best_row+6 best_row+6 best_row-6 best_row-6];
                    plot(app.Kif7only,X,Y);
                    plot(app.Ciliaonly,X,Y);
                    %plot(app.Kif7only,best_column,best_row,'ro','MarkerSize',10);
                    hold(app.Kif7only,'off');
                    hold(app.Ciliaonly,'off');
                case 'Bg'
                    u=app.Kif7only.CurrentPoint;
                    if app.Framenumber.Value == 1
                       
                       app.Background_X=int16(u(1,1));
                       app.Background_Y=int16(u(1,2));
                       app.Results(app.Framenumber.Value,6)=int16(u(1,1));
                       app.Results(app.Framenumber.Value,7)=int16(u(1,2));

                       background = sum(app.I(int16(u(1,2))-6:int16(u(1,2))+6,int16(u(1,1))-6:int16(u(1,1))+6),'all');%%
                       app.Results(app.Framenumber.Value,4)=background;%%
                       app.Results(app.Framenumber.Value,5)=app.Results(app.Framenumber.Value,3)-background; %%

                       hold(app.Kif7only,'on');
                       hold(app.Ciliaonly,'on');
                       X=[app.Background_X-6 app.Background_X-6 app.Background_X+6 app.Background_X+6 app.Background_X-6];
                       Y=[app.Background_Y-6 app.Background_Y+6 app.Background_Y+6 app.Background_Y-6 app.Background_Y-6];
                       plot(app.Kif7only,X,Y);
                       plot(app.Ciliaonly,X,Y);
                       %plot(app.Kif7only,best_column,best_row,'ro','MarkerSize',10);
                       hold(app.Kif7only,'off');
                       hold(app.Ciliaonly,'off');
                    else
                       app.Background_X=int16(u(1,1));
                       app.Background_Y=int16(u(1,2));
                       app.Results(app.Framenumber.Value,6)=int16(u(1,1));
                       app.Results(app.Framenumber.Value,7)=int16(u(1,2));
                       
                       background = sum(app.I(int16(u(1,2))-6:int16(u(1,2))+6,int16(u(1,1))-6:int16(u(1,1))+6),'all');
                       app.Results(app.Framenumber.Value,4)=background;
                       app.Results(app.Framenumber.Value,5)=app.Results(app.Framenumber.Value,3)-background;                
                      
                       plot(app.Kif7TipIntensity,app.Results(:,5));
                       app.Kif7TipIntensity.XLim=[0, app.nframes];
                       plot(app.Kif7IntensityCilia,app.Results(:,4));
                       app.Kif7IntensityCilia.XLim=[0,app.nframes];
                       hold(app.Kif7only,'on');
                       hold(app.Ciliaonly,'on');
                       X=[int16(u(1,1))-6 int16(u(1,1))-6 int16(u(1,1))+6 int16(u(1,1))+6 int16(u(1,1))-6];
                       Y=[int16(u(1,2))-6 int16(u(1,2))+6 int16(u(1,2))+6 int16(u(1,2))-6 int16(u(1,2))-6];
                       plot(app.Kif7only,X,Y);
                       plot(app.Ciliaonly,X,Y);
                       %plot(app.Kif7only,best_column,best_row,'ro','MarkerSize',10);
                       hold(app.Kif7only,'off');
                       hold(app.Ciliaonly,'off');
                    end
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportKIF7dataButton
        function ImportKIF7dataButtonPushed(app, event)
        [app.namei,app.pathi]=uigetfile('*.tif','Channel1');
        cd(app.pathi);
        info=imfinfo(app.namei);
        app.nframes=length(info); %Define frame number
        app.I=imread(app.namei,1);
        min1=min(min(app.I));
        max1=max(max(app.I));
        app.MINSpinner.Value=double(min1);
        app.MAXSpinner.Value=double(max1);
       % set(app.Kif7only,'PickableParts','all');
        app.Kif7Image=imshow(app.I,[min1,max1],'Parent',app.Kif7only);
        %app.Kif7Image=imshow(app.I,[min1,max1],'Parent',app.Kif7only, ...
            %'XData',[1 app.Kif7only.Position(3)], ...
            %'YData',[1 app.Kif7only.Position(4)]);
        %impixelinfo(handles.image1);
        %app.Kif7only.XLim = [0 app.Kif7Image.XData(2)];
        %app.Kif7only.YLim = [0 app.Kif7Image.YData(2)];x
        app.Framenumber.Value=1;
        app.Kif7Image.ButtonDownFcn = @app.ImageButtonDownCallback;
        app.Kif7_X=1;
        app.Kif7_Y=1;
        app.Results(1,:)=[app.Kif7_X,app.Kif7_Y, 0,0,0,0,0];
        end

        % Value changed function: Framenumber
        function FramenumberValueChanged(app, event)
            value = app.Framenumber.Value;
            if value>0 && value <= app.nframes
                app.I=imread(app.namei,value);
                min1=app.MINSpinner.Value;
                max1=app.MAXSpinner.Value;
                app.Kif7Image=imshow(app.I,[min1,max1],'Parent',app.Kif7only);
%                 app.Kif7Image=imshow(app.I,[min1,max1],'Parent',app.Kif7only, ...
%                     'XData',[1 app.Kif7only.Position(3)], ...
%                     'YData',[1 app.Kif7only.Position(4)]);
                %app.Kif7only.XLim = [0 handle.XData(2)];
                %app.Kif7only.YLim = [0 handle.YData(2)];
                app.Kif7Image.ButtonDownFcn = @app.ImageButtonDownCallback;
                plot(app.Kif7TipIntensity,app.Results(:,5)');
                app.Kif7TipIntensity.XLim=[0, app.nframes];
                plot(app.Kif7IntensityCilia,app.Results(:,4)');
                app.Kif7IntensityCilia.XLim=[0,app.nframes];
                app.J=imread(app.namej,value);
                min1=min(min(app.J));
                max1=max(max(app.J));
                imshow(app.J,[min1,max1],'Parent',app.Ciliaonly);
                bw = app.J > 300; %Change this number for thresholding
                bw = uint16(bw);
%                 handle=imshow(app.J,[min1,max1],'Parent',app.Ciliaonly, ...
%                     'XData',[1 app.Ciliaonly.Position(3)], ...
%                     'YData',[1 app.Ciliaonly.Position(4)]);
%                 app.Ciliaonly.XLim = [0 handle.XData(2)];
%                 app.Ciliaonly.YLim = [0 handle.YData(2)];                
            end

            if value > app.nframes
                app.Framenumber.Value = app.nframes;
            end
            
            if value <= 0
                app.Framenumber.Value = 1;
            end
            
            if value <= size(app.Results,1)
                
                hold(app.Kif7only,'on');
                hold(app.Ciliaonly,'on');
                hold(app.Kif7TipIntensity,'on');
                hold(app.Kif7IntensityCilia,'on');                
                best_column = app.Results(value,1);
                best_row = app.Results(value,2);
                X=[best_column-6 best_column-6 best_column+6 best_column+6 best_column-6];
                Y=[best_row-6 best_row+6 best_row+6 best_row-6 best_row-6];
                plot(app.Kif7only,X,Y,'LineWidth',2);
                plot(app.Ciliaonly,X,Y,"LineWidth",2);
                
                background_X=app.Results(value,6);
                background_y=app.Results(value,7);
                X=[background_X-6 background_X-6 background_X+6 background_X+6 background_X-6];
                Y=[background_y-6 background_y+6 background_y+6 background_y-6 background_y-6];
                plot(app.Kif7only,X,Y);
                %Draw mask on Kif7Only
%                 original_size = size(app.I);
%                 for i = 1:original_size(1)
%                     for j = 1: original_size(2)
%                         if bw(i,j) > 0
%                             scatter(app.Kif7only,j,i,'MarkerEdgeAlpha',0.5);
%                         end
%                     end
%                 end
                plot(app.Kif7TipIntensity,value,app.Results(value,5),'ro');
                plot(app.Kif7IntensityCilia,value,app.Results(value,4),'ro');
                %plot(app.Kif7only,best_column,best_row,'ro','MarkerSize',10);
                hold(app.Kif7only,'off');
                hold(app.Ciliaonly,'off');
                hold(app.Kif7TipIntensity,'off');
                hold(app.Kif7IntensityCilia,'off');
            end

        end

        % Button pushed function: ImportCiliadataButton
        function ImportCiliadataButtonPushed(app, event)
            [app.namej,app.pathi]=uigetfile('*.tif','Channel1');
            cd(app.pathi);
            app.J=imread(app.namej,1);
            %axes(handles.image1);
            min1=min(min(app.J));
            max1=max(max(app.J));
            imshow(app.J,[min1,max1],'Parent',app.Ciliaonly);
%             handle=imshow(app.J,[min1,max1],'Parent',app.Ciliaonly, ...
%                     'XData',[1 app.Ciliaonly.Position(3)], ...
%                     'YData',[1 app.Ciliaonly.Position(4)]);
            %app.Ciliaonly.XLim = [0 handle.XData(2)];
            %app.Ciliaonly.YLim = [0 handle.YData(2)];  
            %impixelinfo(handles.image1);
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            
        [name,pathf]=uigetfile('*.mat','workspace');
        cd(pathf);
        temp=load(name);
        app.Results=temp.M;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            M = app.Results;
            dataname = app.filename.Value;
            save( dataname,'M');        
        end

        % Button pushed function: AutoAnalyzeButton
        function AutoAnalyzeButtonPushed(app, event)
            orignal_size = size(app.I);
            switch app.Switch.Value
                case 'Signal'
                     for k = app.Framenumber.Value:app.nframes
                        app.I=imread(app.namei,k);
                        app.J=imread(app.namej,k);
                        column=app.Results(k-1,1);
                        row=app.Results(k-1,2);
                        background_x=app.Results(k-1,6);
                        background_y=app.Results(k-1,7);
                        
                        mask_total = 0;
                        mask_noise = 0;
                        best_column = column;
                        best_row = row;
            
                        for i = -10:10
                            for j = -10:10
                                if row+i-6>=1 && row+i+6<=orignal_size(1) && column+j-6>=1 && column+j+6<=orignal_size(2) 
                                    ROI = app.I(row+i-6:row+i+6,column+j-6:column+j+6);
                                    [total, background] = calculate_mask(app,ROI);
                                    if total > mask_total
                                        mask_total = total;
                                        best_column = column + j;
                                        best_row = row + i;
                                        %mask_max = max(ROI,[],'all');
                                    end
                                end
                            end
                        end
                        app.Kif7_X=best_column;
                        app.Kif7_Y=best_row;
                        app.Results(k,1)=best_column;
                        app.Results(k,2)=best_row;
                        app.Results(k,3)=mask_total;
                        mask_noise = sum(app.I(background_y-6:background_y+6,background_x-6:background_x+6),'all');
                        app.Results(k,4)=mask_noise;
                        app.Results(k,5)=mask_total-mask_noise;
                        app.Results(k,6)=background_x;
                        app.Results(k,7)=background_y;
        
                        %Calculate Kif7 Intensity within the Cilia Mask
                        bw = app.J > 300;
                        bw = uint16(bw);
                        filtered = bw.*app.I;
                        original_size = size(app.J);
                        cilia_intensity = 0 ;
                        for m = 1: original_size(1)
                            for n = 1: original_size(2)
                                if  filtered(m,n) > 0
                                    cilia_intensity = cilia_intensity + filtered(m,n)-mask_noise;
                                end
                            end
                        end
                       % app.Results(k,5) = cilia_intensity;
                       % app.Results(k,5) = sum(filtered,'all');

                    end
                case 'Bg'
                    for k = app.Framenumber.Value:app.nframes
                        app.I=imread(app.namei,k);

                        background_x=app.Results(k-1,6);
                        background_y=app.Results(k-1,7);
                        
                        mask_noise = sum(app.I(background_y-6:background_y+6,background_x-6:background_x+6),'all');
                        app.Results(k,5)=app.Results(k,3)-mask_noise;
                        app.Results(k,4)=mask_noise;
                        app.Results(k,6)=background_x;
                        app.Results(k,7)=background_y;
                    end

            end
           
            %plot(app.Kif7TipIntensity,app.Results(:,3)');
            %hold(app.Kif7TipIntensity,'on');
            plot(app.Kif7TipIntensity,app.Results(:,5));
            %plot(app.Kif7TipIntensity,app.Results(:,4)');
            %hold(app.Kif7TipIntensity,'off');
            app.Kif7TipIntensity.XLim=[0, app.nframes];
            plot(app.Kif7IntensityCilia,app.Results(:,4));
            app.Kif7IntensityCilia.XLim=[0,app.nframes];
         
            %app.Kif7TipIntensity.YLim=[min(app.Results(:,4),[],'all'), max(app.Results(:,4),[],'all')];
        end

        % Value changed function: MAXSpinner
        function MAXSpinnerValueChanged(app, event)
            max1 = app.MAXSpinner.Value;
            min1 = app.MINSpinner.Value;
            app.Kif7Image=imshow(app.I,[min1,max1],'Parent',app.Kif7only);
            app.Kif7Image.ButtonDownFcn = @app.ImageButtonDownCallback;
        end

        % Value changed function: MINSpinner
        function MINSpinnerValueChanged(app, event)
            max1 = app.MAXSpinner.Value;
            min1 = app.MINSpinner.Value;
            app.Kif7Image=imshow(app.I,[min1,max1],'Parent',app.Kif7only);
            app.Kif7Image.ButtonDownFcn = @app.ImageButtonDownCallback;
        end

        % Button pushed function: ReanalyzeButton
        function ReanalyzeButtonPushed(app, event)
            [app.namei,app.pathi]=uigetfile('*.tif','Channel1');
            cd(app.pathi);
            info=imfinfo(app.namei);
            app.nframes=length(info); %Define frame number
            app.I=imread(app.namei,1);
            min1=min(min(app.I));
            max1=max(max(app.I));
            app.MINSpinner.Value=double(min1);
            app.MAXSpinner.Value=double(max1);
            
            for k = 1:app.nframes
                        app.I=imread(app.namei,k);
                        column=app.Results(k,1);
                        row=app.Results(k,2);
                        background_x=app.Results(k,6);
                        background_y=app.Results(k,7);
                        ROI = app.I(row-6:row+6,column-6:column+6);
                        [total, background] = calculate_mask(app,ROI);
                        app.Results(k,3)=total;
                        mask_noise = sum(app.I(background_y-6:background_y+6,background_x-6:background_x+6),'all');
                        app.Results(k,5)=app.Results(k,3)-mask_noise;
                        app.Results(k,4)=mask_noise;
            end
            plot(app.Kif7TipIntensity,app.Results(:,5));
            app.Kif7TipIntensity.XLim=[0, app.nframes];
            plot(app.Kif7IntensityCilia,app.Results(:,4));
            app.Kif7IntensityCilia.XLim=[0,app.nframes];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Kif7TipIntensity = uiaxes(app.UIFigure);
            title(app.Kif7TipIntensity, 'Kif7 Tip Intensity')
            xlabel(app.Kif7TipIntensity, 'Time(hh:mm)')
            ylabel(app.Kif7TipIntensity, {'Kif7 fluoresccent Intensity '; 'at the tip of cilium-Bg(a.u)'})
            zlabel(app.Kif7TipIntensity, 'Z')
            app.Kif7TipIntensity.SubtitleFontWeight = 'bold';
            app.Ciliaonly = uiaxes(app.UIFigure);
            title(app.Ciliaonly, 'Cilia')
            app.Ciliaonly.XTick = [];
            app.Ciliaonly.XTickLabel = {};
            app.Ciliaonly.YTick = [];
            app.Ciliaonly.YTickLabel = {};
            app.Ciliaonly.ZTick = [];
            app.Kif7IntensityCilia = uiaxes(app.UIFigure);
            title(app.Kif7IntensityCilia, 'Kif7 Fluorescent Intensity in the Cilia')
            xlabel(app.Kif7IntensityCilia, 'Time(hh:mm)')
            ylabel(app.Kif7IntensityCilia, 'Bg')
            zlabel(app.Kif7IntensityCilia, 'Z')
            app.Kif7IntensityCilia.SubtitleFontWeight = 'bold';
            app.Kif7only = uiaxes(app.UIFigure);
            title(app.Kif7only, 'Cilia')
            app.Kif7only.XTick = [];
            app.Kif7only.XTickLabel = '';
            app.Kif7only.YTick = [];
            app.Kif7only.YTickLabel = '';
            app.Kif7only.ZTick = [];
            app.FramenumberSpinnerLabel = uilabel(app.UIFigure);
            app.FramenumberSpinnerLabel.Text = {'Frame number'; ''};
            app.Framenumber = uispinner(app.UIFigure);
            app.Framenumber.ValueChangedFcn = createCallbackFcn(app, @FramenumberValueChanged, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.ImportKIF7dataButton = uibutton(app.UIFigure, 'push');
            app.ImportKIF7dataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportKIF7dataButtonPushed, true);
            app.ImportKIF7dataButton.Text = 'Import KIF7 data';
            app.ImportCiliadataButton = uibutton(app.UIFigure, 'push');
            app.ImportCiliadataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportCiliadataButtonPushed, true);
            app.ImportCiliadataButton.Text = 'Import Cilia data';
            app.AutoAnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AutoAnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AutoAnalyzeButtonPushed, true);
            app.AutoAnalyzeButton.Text = 'Auto Analyze';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.filename = uieditfield(app.UIFigure, 'text');
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Signal', 'Bg'};
            app.Switch.Value = 'Signal';
            app.MAXSpinnerLabel = uilabel(app.UIFigure);
            app.MAXSpinnerLabel.Text = 'MAX';
            app.MAXSpinner = uispinner(app.UIFigure);
            app.MAXSpinner.ValueChangedFcn = createCallbackFcn(app, @MAXSpinnerValueChanged, true);
            app.MINSpinnerLabel = uilabel(app.UIFigure);
            app.MINSpinnerLabel.Text = 'MIN';
            app.MINSpinner = uispinner(app.UIFigure);
            app.MINSpinner.ValueChangedFcn = createCallbackFcn(app, @MINSpinnerValueChanged, true);
            app.ReanalyzeButton = uibutton(app.UIFigure, 'push');
            app.ReanalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @ReanalyzeButtonPushed, true);
            app.ReanalyzeButton.Text = 'Re-analyze';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TwoChannelsKif7Gli2_SquareMask_13x13

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end